classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModelSignalGeneratorUIFigure    matlab.ui.Figure
        ClearTableButton_2              matlab.ui.control.Button
        ClearTableButton                matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        UITable_2                       matlab.ui.control.Table
        FouriercomplexLabel_2           matlab.ui.control.Label
        FourierLabel_2                  matlab.ui.control.Label
        FouriercomplexLabel             matlab.ui.control.Label
        FourierLabel                    matlab.ui.control.Label
        SignalLabel                     matlab.ui.control.Label
        TheNumberOfFourierSeriesTermsEditFieldLabelKMax  matlab.ui.control.Label
        TheNumberOfFourierSeriesTermsEditFieldLabelKMin  matlab.ui.control.Label
        TheNumberOfFourierSeriesTermsEditField  matlab.ui.control.NumericEditField
        ThenumberofFourierseriestermsKkpKN4Label  matlab.ui.control.Label
        NumberOfAccumulationsEditField  matlab.ui.control.NumericEditField
        NumberOfAccumulationsLabel      matlab.ui.control.Label
        SignalTypeDropDown              matlab.ui.control.DropDown
        ModelSignalGeneratorLabel       matlab.ui.control.Label
        SignalTypeLabel                 matlab.ui.control.Label
        NumberOfPointsNEditField        matlab.ui.control.NumericEditField
        NumberOfPointsLabel             matlab.ui.control.Label
        NoiseTypeDropDown               matlab.ui.control.DropDown
        NoiseTypeLabel                  matlab.ui.control.Label
        SignalAmplitudeEditField        matlab.ui.control.NumericEditField
        SignalAmplitudeLabel            matlab.ui.control.Label
        NoiseSKOEditField               matlab.ui.control.NumericEditField
        NoiseSKOLabel                   matlab.ui.control.Label
        PeriodsNumberkpEditField        matlab.ui.control.NumericEditField
        PeriodsNumberLabel              matlab.ui.control.Label
        QuitButton                      matlab.ui.control.Button
        GenerateButton                  matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        TableData
    end
    
    methods (Access = private)
        function UpdateTableChart(app)
            if (isempty(app.TableData))
                % Empty the data
                app.UITable.Data = [];

                % Clear chart
                cla(app.UIAxes_2);
            else
                % Load data from variable
                app.UITable.Data = array2table(app.TableData,'VariableNames',{'K','SKO'});
                x = app.UITable.Data.K;
                y = app.UITable.Data.SKO;
    
                % ----------------------------------------
                % Draw chart
                % ----------------------------------------
                % For interpolation, we need at least 2 different points
                if (size(app.TableData, 1) >= 2)
                    % Create 100 more points for interpolation
                    xq = linspace(min(x), max(x), 100);
                    
                    % Apply interpolation
                    yq = interp1(x, y, xq, 'pchip');
                    
                    % Draw chart with soft line and points
                    plot(app.UIAxes_2, xq, yq, 'b-', x, y, 'bo');
                end
                % ----------------------------------------
            end
        end
        
        function UpdateMinLimit(app)
            % Update text in label for minimum
            app.TheNumberOfFourierSeriesTermsEditFieldLabelKMin.Text = app.PeriodsNumberkpEditField.Value + " <=";
            % Update edit field minimum and maximum (do not include maximum in edit field properties)
            app.TheNumberOfFourierSeriesTermsEditField.Limits = [app.PeriodsNumberkpEditField.Value, app.NumberOfPointsNEditField.Value / 4];
        end
        
        function UpdateMaxLimit(app)
            % Update text in label for maximum
            app.TheNumberOfFourierSeriesTermsEditFieldLabelKMax.Text = "<= " + app.NumberOfPointsNEditField.Value / 4;
            % Update edit field minimum and maximum (do not include maximum in edit field properties)
            app.TheNumberOfFourierSeriesTermsEditField.Limits = [app.PeriodsNumberkpEditField.Value, app.NumberOfPointsNEditField.Value / 4];
        end
        
        function ClearTable(app)
            app.TableData = [];
            app.UpdateTableChart();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create ".m" file with ".mlapp" MATLAB code (for track changes in GIT)
            writelines(evalc('type(mfilename(''fullpath'') + ".mlapp")'), mfilename('fullpath') + ".m");

            app.UpdateMinLimit();
            app.UpdateMaxLimit();
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            % Close the app
            app.delete();
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            % Количество периодов гармонической функции (kp)
            periods_number = app.PeriodsNumberkpEditField.Value;
            
            % Количество членов ряда Фурье (K)
            K = app.TheNumberOfFourierSeriesTermsEditField.Value;

            % Количество отсчетов (элементов массива y(t)) (number_of_points)
            number_of_points = app.NumberOfPointsNEditField.Value;
            
            noise_sko = app.NoiseSKOEditField.Value;
            signal_aplitude = app.SignalAmplitudeEditField.Value;
            number_of_accumulations = app.NumberOfAccumulationsEditField.Value;

            % Create X array
            x = 1:number_of_points;

            % Create Y array
            y_accumulated = zeros(1, number_of_points);

            % Define frequency
            frequency = 2 * pi * periods_number / number_of_points;

            % ----------------------------------------
            % Высчитываем точки самого сигнала
            % ----------------------------------------
            for iteration = 1:number_of_accumulations
                % Create Y array
                y = zeros(1, number_of_points);

                % Generate noise
                if (app.NoiseTypeDropDown.Value == "Normally Distributed")
                    noise = randn(number_of_points);
                elseif (app.NoiseTypeDropDown.Value == "White Gaussian Noise")
                    noise = wgn(number_of_points, 1, 0);
                else
                    noise = zeros(1, number_of_points);
                end
                noise = noise * noise_sko;

                for i = 1:number_of_points
                    % Fill Y array by selected signal type
                    if (app.SignalTypeDropDown.Value == "Harmonic (Sinusoidal)")
                        y(i) = sin(frequency * i);
                    elseif (app.SignalTypeDropDown.Value == "Sawtooth")
                        y(i) = sawtooth(frequency * i);
                    elseif (app.SignalTypeDropDown.Value == "Triangular")
                        y(i) = sawtooth(frequency * i, 0.5);
                    elseif (app.SignalTypeDropDown.Value == "Rectangular Pulses")
                        y(i) = square(frequency * i);
                    elseif (app.SignalTypeDropDown.Value == "y_accumulated(x) = abs(sin(x))")
                        y(i) = abs(sin(frequency * i));
                    end
                    y(i) = y(i) * signal_aplitude;
                    y(i) = y(i) + noise(i);
                end

                % Accumulate results
                y_accumulated = y_accumulated + y;
            end

            % Normalize the accumulated results
            y_accumulated = y_accumulated / number_of_accumulations;
            % ----------------------------------------

            % ----------------------------------------
            % Draw graph in axes object
            % ----------------------------------------
            plot(app.UIAxes, x, y_accumulated, 'r');
            % ----------------------------------------

            % ----------------------------------------
            % Высчитываем точки ряда Фурье
            % ----------------------------------------
            Sa0 = sum(y_accumulated) / number_of_points;

            Sa = zeros(1,K);
            Sb = zeros(1,K);
            for i = 1:number_of_points
                for j = 1:K
                    Sa(j) = Sa(j) + y_accumulated(i) * cos((j) * 2 * pi * (i - 1 - number_of_points / 2) / number_of_points);
                    Sb(j) = Sb(j) + y_accumulated(i) * sin((j) * 2 * pi * (i - 1 - number_of_points / 2) / number_of_points);
                end
            
            end
            for j = 1:K
                Sa(j) = Sa(j) * 2 / number_of_points;
                Sb(j) = Sb(j) * 2 / number_of_points;
            end

            y_fourier = zeros(1,number_of_points);
            for i = 1:number_of_points
                for j = 1:K
                    y_fourier(i) = y_fourier(i) + Sa(j) * cos(j * 2 * pi * (i - 1 - number_of_points / 2) / number_of_points) + Sb(j) * sin(j * 2 * pi * (i - 1 - number_of_points / 2) / number_of_points);
                end
                  y_fourier(i) = Sa0 + y_fourier(i);
            end
            % ----------------------------------------

            % ----------------------------------------
            % Draw new chart in the same figure
            % ----------------------------------------
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, i, y_fourier, 'b');
            hold(app.UIAxes, 'off');
            % ----------------------------------------
            
            % ----------------------------------------
            % Находим СКО
            % ----------------------------------------
            % Абсолютная погрешность восстановления  
            y_fourier_deviation = zeros(1,number_of_points);
            for i = 1:number_of_points
                y_fourier_deviation(i) = y_fourier(i) - y_accumulated(i);
            end
            y_fourier_deviation_in_percents = y_fourier_deviation / (max(y_accumulated) - min(y_accumulated)) * 100;

            % СКО в процентах
            SKO_in_percents = std(y_fourier_deviation_in_percents);
            % ----------------------------------------
            
            % ----------------------------------------
            % Обновляем правый график зависимости погрешности от K
            % ----------------------------------------
            % Add new row to the table
            app.TableData = [app.TableData; K SKO_in_percents];

            % Sort values to make sure they are ascending
            app.TableData = sortrows(app.TableData);

            % Filter rows with same first column (K)
            [~,ia,~] = unique(app.TableData(:,1), 'rows');
            app.TableData = app.TableData(ia,:);

            % Redraw table chart
            app.UpdateTableChart();
            % ----------------------------------------
        end

        % Value changed function: NoiseTypeDropDown
        function NoiseTypeDropDownValueChanged(app, event)
            if (app.NoiseTypeDropDown.Value == "None")
                app.NoiseSKOLabel.Enable = "off";
                app.NoiseSKOEditField.Enable = "off";
            else
                app.NoiseSKOLabel.Enable = "on";
                app.NoiseSKOEditField.Enable = "on";
            end
            ClearTable(app);
        end

        % Value changed function: NumberOfPointsNEditField
        function NumberOfPointsNEditFieldValueChanged(app, event)
            app.UpdateMaxLimit();
            ClearTable(app);
        end

        % Value changed function: PeriodsNumberkpEditField
        function PeriodsNumberkpEditFieldValueChanged(app, event)
            app.UpdateMinLimit();
            ClearTable(app);
        end

        % Button pushed function: ClearTableButton
        function ClearTableButtonPushed(app, event)
            ClearTable(app);
        end

        % Value changed function: NoiseSKOEditField
        function NoiseSKOEditFieldValueChanged(app, event)
            ClearTable(app);
        end

        % Value changed function: SignalTypeDropDown
        function SignalTypeDropDownValueChanged(app, event)
            ClearTable(app);
        end

        % Value changed function: NumberOfAccumulationsEditField
        function NumberOfAccumulationsEditFieldValueChanged(app, event)
            ClearTable(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModelSignalGeneratorUIFigure = uifigure('Visible', 'off');
            app.ModelSignalGeneratorUIFigure.Name = 'Model Signal Generator';
            app.UIAxes = uiaxes(app.ModelSignalGeneratorUIFigure);
            xlabel(app.UIAxes, 'N')
            ylabel(app.UIAxes, 'S')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.ModelSignalGeneratorUIFigure);
            xlabel(app.UIAxes_2, 'K')
            ylabel(app.UIAxes_2, 'SKO')
            zlabel(app.UIAxes_2, 'Z')
            app.GenerateButton = uibutton(app.ModelSignalGeneratorUIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.QuitButton = uibutton(app.ModelSignalGeneratorUIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.PeriodsNumberLabel = uilabel(app.ModelSignalGeneratorUIFigure);
            app.PeriodsNumberLabel.Text = 'Periods Number (kp):';
            app.PeriodsNumberkpEditField = uieditfield(app.ModelSignalGeneratorUIFigure, 'numeric');
            app.PeriodsNumberkpEditField.ValueDisplayFormat = '%9.1f';
            app.PeriodsNumberkpEditField.ValueChangedFcn = createCallbackFcn(app, @PeriodsNumberkpEditFieldValueChanged, true);
            app.PeriodsNumberkpEditField.Value = 4.5;
            app.NoiseSKOLabel = uilabel(app.ModelSignalGeneratorUIFigure);
            app.NoiseSKOLabel.Enable = 'off';
            app.NoiseSKOLabel.Text = 'Noise SKO:';
            app.NoiseSKOEditField = uieditfield(app.ModelSignalGeneratorUIFigure, 'numeric');
            app.NoiseSKOEditField.ValueDisplayFormat = '%9.1f';
            app.NoiseSKOEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseSKOEditFieldValueChanged, true);
            app.NoiseSKOEditField.Enable = 'off';
            app.NoiseSKOEditField.Value = 0.1;
            app.SignalAmplitudeLabel = uilabel(app.ModelSignalGeneratorUIFigure);
            app.SignalAmplitudeLabel.Text = 'Signal Amplitude:';
            app.SignalAmplitudeEditField = uieditfield(app.ModelSignalGeneratorUIFigure, 'numeric');
            app.SignalAmplitudeEditField.ValueDisplayFormat = '%9.1f';
            app.SignalAmplitudeEditField.Value = 1;
            app.NoiseTypeLabel = uilabel(app.ModelSignalGeneratorUIFigure);
            app.NoiseTypeLabel.Text = 'Noise Type:';
            app.NoiseTypeDropDown = uidropdown(app.ModelSignalGeneratorUIFigure);
            app.NoiseTypeDropDown.Items = {'None', 'Normally Distributed', 'White Gaussian Noise'};
            app.NoiseTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseTypeDropDownValueChanged, true);
            app.NoiseTypeDropDown.Value = 'None';
            app.NumberOfPointsLabel = uilabel(app.ModelSignalGeneratorUIFigure);
            app.NumberOfPointsLabel.Text = 'Number Of Points (N):';
            app.NumberOfPointsNEditField = uieditfield(app.ModelSignalGeneratorUIFigure, 'numeric');
            app.NumberOfPointsNEditField.RoundFractionalValues = 'on';
            app.NumberOfPointsNEditField.ValueDisplayFormat = '%.0f';
            app.NumberOfPointsNEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfPointsNEditFieldValueChanged, true);
            app.NumberOfPointsNEditField.Value = 1024;
            app.SignalTypeLabel = uilabel(app.ModelSignalGeneratorUIFigure);
            app.SignalTypeLabel.Text = 'Signal Type:';
            app.ModelSignalGeneratorLabel = uilabel(app.ModelSignalGeneratorUIFigure);
            app.ModelSignalGeneratorLabel.Text = 'Model Signal Generator';
            app.SignalTypeDropDown = uidropdown(app.ModelSignalGeneratorUIFigure);
            app.SignalTypeDropDown.Items = {'Harmonic (Sinusoidal)', 'Sawtooth', 'Triangular', 'Rectangular Pulses', 'f(x) = abs(sin(x))'};
            app.SignalTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalTypeDropDownValueChanged, true);
            app.SignalTypeDropDown.Value = 'Harmonic (Sinusoidal)';
            app.NumberOfAccumulationsLabel = uilabel(app.ModelSignalGeneratorUIFigure);
            app.NumberOfAccumulationsLabel.Text = 'Number Of Accumulations:';
            app.NumberOfAccumulationsEditField = uieditfield(app.ModelSignalGeneratorUIFigure, 'numeric');
            app.NumberOfAccumulationsEditField.RoundFractionalValues = 'on';
            app.NumberOfAccumulationsEditField.ValueDisplayFormat = '%.0f';
            app.NumberOfAccumulationsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfAccumulationsEditFieldValueChanged, true);
            app.NumberOfAccumulationsEditField.Value = 1;
            app.ThenumberofFourierseriestermsKkpKN4Label = uilabel(app.ModelSignalGeneratorUIFigure);
            app.ThenumberofFourierseriestermsKkpKN4Label.Text = 'The number of Fourier series terms (K, kp <= K <= N/4):';
            app.TheNumberOfFourierSeriesTermsEditField = uieditfield(app.ModelSignalGeneratorUIFigure, 'numeric');
            app.TheNumberOfFourierSeriesTermsEditField.RoundFractionalValues = 'on';
            app.TheNumberOfFourierSeriesTermsEditField.ValueDisplayFormat = '%.0f';
            app.TheNumberOfFourierSeriesTermsEditField.Value = 128;
            app.TheNumberOfFourierSeriesTermsEditFieldLabelKMin = uilabel(app.ModelSignalGeneratorUIFigure);
            app.TheNumberOfFourierSeriesTermsEditFieldLabelKMin.Text = '2 <=';
            app.TheNumberOfFourierSeriesTermsEditFieldLabelKMax = uilabel(app.ModelSignalGeneratorUIFigure);
            app.TheNumberOfFourierSeriesTermsEditFieldLabelKMax.Text = '<= 256';
            app.SignalLabel = uilabel(app.ModelSignalGeneratorUIFigure);
            app.SignalLabel.Text = 'Signal';
            app.FourierLabel = uilabel(app.ModelSignalGeneratorUIFigure);
            app.FourierLabel.Text = 'Fourier';
            app.FouriercomplexLabel = uilabel(app.ModelSignalGeneratorUIFigure);
            app.FouriercomplexLabel.Text = 'Fourier (complex)';
            app.FourierLabel_2 = uilabel(app.ModelSignalGeneratorUIFigure);
            app.FourierLabel_2.Text = 'Fourier';
            app.FouriercomplexLabel_2 = uilabel(app.ModelSignalGeneratorUIFigure);
            app.FouriercomplexLabel_2.Text = 'Fourier (complex)';
            app.UITable_2 = uitable(app.ModelSignalGeneratorUIFigure);
            app.UITable_2.ColumnName = {'K'; 'SKO'};
            app.UITable_2.RowName = {};
            app.UITable = uitable(app.ModelSignalGeneratorUIFigure);
            app.UITable.ColumnName = {'K'; 'SKO'};
            app.UITable.RowName = {};
            app.ClearTableButton = uibutton(app.ModelSignalGeneratorUIFigure, 'push');
            app.ClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTableButtonPushed, true);
            app.ClearTableButton.Text = 'Clear table';
            app.ClearTableButton_2 = uibutton(app.ModelSignalGeneratorUIFigure, 'push');
            app.ClearTableButton_2.Text = 'Clear table';
            % Show the figure after all components are created
            app.ModelSignalGeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModelSignalGeneratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModelSignalGeneratorUIFigure)
        end
    end
end